classdef LINGKARAN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HAPUSButton                    matlab.ui.control.Button
        HITUNGButton                   matlab.ui.control.Button
        KELILINGEditField              matlab.ui.control.NumericEditField
        KELILINGEditFieldLabel         matlab.ui.control.Label
        LUASEditField                  matlab.ui.control.NumericEditField
        LUASEditFieldLabel             matlab.ui.control.Label
        JARIJARIEditField              matlab.ui.control.NumericEditField
        JARIJARIEditFieldLabel         matlab.ui.control.Label
        LUASDANKELILINGLINGKARANLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HITUNGButton
        function HITUNGButtonPushed(app, event)
            r=app.JARIJARIEditField.Value;
            luas=pi*r^2;
            keliling=2*r*pi;
            app.LUASEditField.Value=luas;
            app.KELILINGEditField.Value=keliling;
        end

        % Button pushed function: HAPUSButton
        function HAPUSButtonPushed(app, event)
            app.JARIJARIEditField.Value=0;
            app.LUASEditField.Value=0;
            app.KELILINGEditField.Value=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LUASDANKELILINGLINGKARANLabel = uilabel(app.UIFigure);
            app.LUASDANKELILINGLINGKARANLabel.Text = 'LUAS DAN KELILING LINGKARAN';
            app.JARIJARIEditFieldLabel = uilabel(app.UIFigure);
            app.JARIJARIEditFieldLabel.Text = 'JARI-JARI =';
            app.JARIJARIEditField = uieditfield(app.UIFigure, 'numeric');
            app.LUASEditFieldLabel = uilabel(app.UIFigure);
            app.LUASEditFieldLabel.Text = 'LUAS =';
            app.LUASEditField = uieditfield(app.UIFigure, 'numeric');
            app.KELILINGEditFieldLabel = uilabel(app.UIFigure);
            app.KELILINGEditFieldLabel.Text = 'KELILING =';
            app.KELILINGEditField = uieditfield(app.UIFigure, 'numeric');
            app.HITUNGButton = uibutton(app.UIFigure, 'push');
            app.HITUNGButton.ButtonPushedFcn = createCallbackFcn(app, @HITUNGButtonPushed, true);
            app.HITUNGButton.Text = 'HITUNG';
            app.HAPUSButton = uibutton(app.UIFigure, 'push');
            app.HAPUSButton.ButtonPushedFcn = createCallbackFcn(app, @HAPUSButtonPushed, true);
            app.HAPUSButton.Text = 'HAPUS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LINGKARAN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end